classdef FinalProj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TabGroup  matlab.ui.container.TabGroup
        HomeTab   matlab.ui.container.Tab
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Image     matlab.ui.control.Image
        AboutTab  matlab.ui.container.Tab
        Image2    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: Button
        function ButtonPushedtrans(app, event)

            morseTransApp = MorseTrans;
            morseTransApp.UIFigure.Visible = 'on';  
            delete(app);  
        

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
         alphabetListApp = AlphabetList;
            alphabetListApp.UIFigure.Visible = 'on';  
            delete(app);
        
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             quizApp = MorseQuiz;
            quizApp.UIFigure.Visible = 'on';  
            delete(app);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HOMEPAGE.png');
            app.Button = uibutton(app.HomeTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushedtrans, true);
            app.Button.Text = '——>';
            app.Button_2 = uibutton(app.HomeTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '——>';
            app.Button_3 = uibutton(app.HomeTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '——>';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image2 = uiimage(app.AboutTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'aboutpage.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end