classdef MorseQuiz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        HOMEButton        matlab.ui.control.Button
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        FalseButton       matlab.ui.control.Button
        QuestionTextArea  matlab.ui.control.TextArea
        STARTButton       matlab.ui.control.Button
        TrueButton        matlab.ui.control.Button
        Image             matlab.ui.control.Image
    end

    properties (Access = public)
        Score           double = 0
        QuestionCount   double = 0
        Questions       cell = {...

           'L in Morse is .-..', true; ...
            'M in Morse is --', true; ...
            'F in Morse is ..-.', true; ...
            'G in Morse is --.', true; ...
            'H in Morse is ....', true; ...
            'I in Morse is ..', true; ...
            'J in Morse is .---', true; ...
           
            'C in Morse is ...-', false; ...
            'D in Morse is ..-', false; ...
            'E in Morse is -.-.', false; ...
            'F in Morse is .', false; ...
            'A in Morse is .-', true; ...
             'K in Morse is -.-', true; ...
            'A in Morse is ...', false; ...
            'Y in Morse is -.--', true; ...
            'Z in Morse is --..', true 
            'B in Morse is .--.', false; ...
            'N in Morse is -.', true; ...
            'O in Morse is ---', true; ...
            'P in Morse is .--.', true; ...
            'Q in Morse is --.-', true; ...
            'R in Morse is .-.', true; ...
             'L in Morse is --', false; ...
            'P in Morse is ---', false; ...
            'Q in Morse is -..-', false; ...
            'R in Morse is ---..', false; ...
            'S in Morse is ...', true; ...
            'T in Morse is -', true; ...
            'U in Morse is ..-', true; ...
            'V in Morse is ...-', true; ...
            'X in Morse is ...-.', false; ...
            'Y in Morse is .-.-.', false; ...
            'Z in Morse is ...--', false 
                'Q in Morse is -..-', false; ...
            'R in Morse is ---..', false; ...
            'S in Morse is ...', true; ...
            'W in Morse is .--', true; ...
            'X in Morse is -..-', true; ...
            'M in Morse is .-..', false; ...
            'N in Morse is .--', false; ...
             'B in Morse is -...', true; ...
            'C in Morse is -.-.', true; ...
            }
        CurrentQuestion string
        CurrentAnswer   logical
    end

    methods (Access = private)
        
        % Generate the next question
        function generateQuestion(app)
            if app.QuestionCount < size(app.Questions, 1)
                app.CurrentQuestion = app.Questions{app.QuestionCount+1, 1};
                app.CurrentAnswer = app.Questions{app.QuestionCount+1, 2};
                app.QuestionTextArea.Value = app.CurrentQuestion;
                app.Label.Text = '';
                app.QuestionCount = app.QuestionCount + 1;
            else
                app.QuestionTextArea.Value = 'Quiz Complete!';
                app.TrueButton.Enable = 'off';
                app.FalseButton.Enable = 'off';
                app.STARTButton.Enable = 'off';
                app.Label.Text = sprintf('Final Score: %d/%d', app.Score, size(app.Questions, 1));
            end
        end
        
        % Check the user's answer
        function checkAnswer(app, userAnswer)
            if userAnswer == app.CurrentAnswer
                app.Label.Text = 'Correct!';
                app.Score = app.Score + 1;
            else
                app.Label.Text = 'Incorrect.';
            end
            app.Label_2.Text = sprintf('Score: %d', app.Score);
        end

    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrueButton
        function TrueButtonPushed(app, event)
           checkAnswer(app, true);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            generateQuestion(app);
        end

        % Button pushed function: FalseButton
        function FalseButtonPushed(app, event)
            checkAnswer(app, false);
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            HomeMenu = FinalProj;
            HomeMenu.UIFigure.Visible = 'on';  
            delete(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'QUIZ.png');
            app.TrueButton = uibutton(app.UIFigure, 'push');
            app.TrueButton.ButtonPushedFcn = createCallbackFcn(app, @TrueButtonPushed, true);
            app.TrueButton.Text = 'TRUE';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.QuestionTextArea = uitextarea(app.UIFigure);
            app.FalseButton = uibutton(app.UIFigure, 'push');
            app.FalseButton.ButtonPushedFcn = createCallbackFcn(app, @FalseButtonPushed, true);
            app.FalseButton.Text = 'FALSE';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorseQuiz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end