classdef MorseTrans < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlayButton      matlab.ui.control.Button
        HOMEButton      matlab.ui.control.Button
        ConvertButton   matlab.ui.control.Button
        TranslatedText  matlab.ui.control.TextArea
        TextTranslate   matlab.ui.control.TextArea
        Image           matlab.ui.control.Image
    end

    properties (Access = public)
        player 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)

        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            HomeMenu = FinalProj;
            HomeMenu.UIFigure.Visible = 'on';  
            delete(app);
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            load('morseAlphabet.mat', 'morse');

            input_text = char(app.TextTranslate.Value);
%% decode   
                if isempty(input_text)
                    app.TranslatedText.Value = '';
                elseif ismember(input_text, '.-/ ')deco = [];
                    
                   input_text = [input_text ' '];
                   lcode = [];
    
                   for j=1:length(input_text)
                       if(strcmp(input_text(j), ' ') | strcmp(input_text(j), '/'))
                           for i=double('a'):double('z')
                               letter = getfield(morse, char(i));
                               if strcmp(lcode, letter)
                                  deco = [deco char(i)];
                               end
                           end
                           for i=0:9
                               numb = getfield(morse, ['n', num2str(i)]);
                               if strcmp(lcode, numb)
                                   deco = [deco, num2str(i)];
                               end
                           end
                           for i=1:4
                               scv=char(morse.scv(i));
                               if strcmp(lcode,scv)
                                   deco = [deco, morse.sc(i)]
                               end
                           end
                           lcode = [];
                       else
                           lcode = [lcode input_text(j)];
                       end
                       if strcmp(input_text(j), '/')
                           deco = [deco ' '];
                       end
                   end
        
                   app.TranslatedText.Value = upper(deco);
%% encode
                else
                    text = lower(input_text);
                    mor = [];
                
                    for i=1:length(text)
                        if isequal(text(i), ' ')
                            mor = [mor '/'];
                        elseif isvarname(text(i))
                            mor = [mor getfield(morse, text(i))];
                            mor = [mor ' '];
                        elseif ismember(text(i), '0123456789')
                            mor = [mor getfield(morse, ['n', text(i)])];
                            mor = [mor ' '];
                        elseif findstr(text(i), morse.sc)
                            mor = [mor char(morse.scv(findstr(text(i), morse.sc)))];
                            mor = [mor ' '];
                        end
                    end
                
                app.TranslatedText.Value = mor;
                end

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            fs = 11025; % sample frequency
            unit = 0.08; % speed
            freq = 600; % pitch

            Dot = sin(2*pi*freq*(0:1/fs:unit)); 
            Dash = sin(2*pi*freq*(0:1/fs:3*unit));  
            ssp = zeros(1, round(unit*fs)); % short space
            lsp = zeros(1, round(3*unit*fs)); % long space
            word_gap = zeros(1, round(7*unit*fs)); % word space

            msou = [];
            if ismember(char(app.TextTranslate.Value), './- ')
                text = char(app.TextTranslate.Value);
            else 
                text = char(app.TranslatedText.Value);
            end

            for i=1:length(text)
                if strcmp(text(i), '.')
                    msou = [msou Dot ssp];
                elseif strcmp(text(i), '-')
                    msou = [msou Dash ssp];
                elseif strcmp(text(i), ' ')
                    msou = [msou lsp];
                elseif strcmp(text(i), '/')
                    msou = [msou word_gap];
                end
            end
            
            app.player = audioplayer(msou, 11025);
            play(app.player);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'translate.png');
            app.TextTranslate = uitextarea(app.UIFigure);
            app.TranslatedText = uitextarea(app.UIFigure);
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MorseTrans

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end